classdef GainTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        LabelEditField        matlab.ui.control.Label            % Address
        AddressField          matlab.ui.control.EditField        % 127.0.0.1
        LabelEditField2       matlab.ui.control.Label            % Port
        PortField             matlab.ui.control.EditField        % 14552
        ConnectButton         matlab.ui.control.Button           % Connect
        ConnectStatus         matlab.ui.control.EditField        % DISCONNECTED
        Label                 matlab.ui.control.Label            % MAVLink PID Tuning Example
        PitchTuning           matlab.ui.container.Panel          % Pitch Tuning
        Label10               matlab.ui.control.Label            % P
        Label11               matlab.ui.control.Label            % I
        Label12               matlab.ui.control.Label            % D
        LabelNumericEditField matlab.ui.control.Label            % Value
        PitchP                matlab.ui.control.NumericEditField % [-Inf Inf]
        Label2                matlab.ui.control.Label            % Value
        PitchI                matlab.ui.control.NumericEditField % [-Inf Inf]
        Label3                matlab.ui.control.Label            % Value
        PitchD                matlab.ui.control.NumericEditField % [-Inf Inf]
        Label4                matlab.ui.control.Label            % Min
        PitchPMin             matlab.ui.control.NumericEditField % [-Inf Inf]
        Label5                matlab.ui.control.Label            % Min
        PitchIMin             matlab.ui.control.NumericEditField % [-Inf Inf]
        Label6                matlab.ui.control.Label            % Min
        PitchDMin             matlab.ui.control.NumericEditField % [-Inf Inf]
        Label7                matlab.ui.control.Label            % Max
        PitchPMax             matlab.ui.control.NumericEditField % [-Inf Inf]
        Label8                matlab.ui.control.Label            % Max
        PitchIMax             matlab.ui.control.NumericEditField % [-Inf Inf]
        Label9                matlab.ui.control.Label            % Max
        PitchDMax             matlab.ui.control.NumericEditField % [-Inf Inf]
        RollTuning            matlab.ui.container.Panel          % Roll Tuning
        Label14               matlab.ui.control.Label            % P
        Label15               matlab.ui.control.Label            % I
        Label16               matlab.ui.control.Label            % D
        Label18               matlab.ui.control.Label            % Value
        RollP                 matlab.ui.control.NumericEditField % [-Inf Inf]
        Label19               matlab.ui.control.Label            % Value
        RollI                 matlab.ui.control.NumericEditField % [-Inf Inf]
        Label20               matlab.ui.control.Label            % Value
        RollD                 matlab.ui.control.NumericEditField % [-Inf Inf]
        Label21               matlab.ui.control.Label            % Min
        RollPMin              matlab.ui.control.NumericEditField % [-Inf Inf]
        Label22               matlab.ui.control.Label            % Min
        RollIMin              matlab.ui.control.NumericEditField % [-Inf Inf]
        Label23               matlab.ui.control.Label            % Min
        RollDMin              matlab.ui.control.NumericEditField % [-Inf Inf]
        Label24               matlab.ui.control.Label            % Max
        RollPMax              matlab.ui.control.NumericEditField % [-Inf Inf]
        Label25               matlab.ui.control.Label            % Max
        RollIMax              matlab.ui.control.NumericEditField % [-Inf Inf]
        Label26               matlab.ui.control.Label            % Max
        RollDMax              matlab.ui.control.NumericEditField % [-Inf Inf]
        YawTuning             matlab.ui.container.Panel          % Yaw Tuning
        Label27               matlab.ui.control.Label            % P
        Label28               matlab.ui.control.Label            % I
        Label29               matlab.ui.control.Label            % D
        Label31               matlab.ui.control.Label            % Value
        YawP                  matlab.ui.control.NumericEditField % [-Inf Inf]
        Label32               matlab.ui.control.Label            % Value
        YawI                  matlab.ui.control.NumericEditField % [-Inf Inf]
        Label33               matlab.ui.control.Label            % Value
        YawD                  matlab.ui.control.NumericEditField % [-Inf Inf]
        Label34               matlab.ui.control.Label            % Min
        YawPMin               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label35               matlab.ui.control.Label            % Min
        YawIMin               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label36               matlab.ui.control.Label            % Min
        YawDMin               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label37               matlab.ui.control.Label            % Max
        YawPMax               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label38               matlab.ui.control.Label            % Max
        YawIMax               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label39               matlab.ui.control.Label            % Max
        YawDMax               matlab.ui.control.NumericEditField % [-Inf Inf]
        PitchPlotBorder       matlab.ui.container.Panel         
        PitchPlot             matlab.ui.control.UIAxes           % Pitch Angle
        RollPlotBorder        matlab.ui.container.Panel         
        RollPlot              matlab.ui.control.UIAxes           % Roll Angle
        YawPlotBorder         matlab.ui.container.Panel         
        YawPlot               matlab.ui.control.UIAxes           % Yaw Angle
        WriteGains            matlab.ui.control.Button           % Write Gains
        RefreshGains          matlab.ui.control.Button           % Refresh Gains
        LockPitchRoll         matlab.ui.control.CheckBox         % Lock Pitch/Roll Gains
        StartMeasurement      matlab.ui.control.Button           % Start Measurement
        Label40               matlab.ui.control.Label            % Stabilize
        Label41               matlab.ui.control.Label            % Rate
        Label42               matlab.ui.control.Label            % P
        Label43               matlab.ui.control.Label            % Value
        PitchStab             matlab.ui.control.NumericEditField % [-Inf Inf]
        Label44               matlab.ui.control.Label            % Min
        PitchStabMin          matlab.ui.control.NumericEditField % [-Inf Inf]
        Label45               matlab.ui.control.Label            % Max
        PitchStabMax          matlab.ui.control.NumericEditField % [-Inf Inf]
        Label46               matlab.ui.control.Label            % Rate
        Label47               matlab.ui.control.Label            % Rate
        Label48               matlab.ui.control.Label            % Stabilize
        Label49               matlab.ui.control.Label            % P
        Label50               matlab.ui.control.Label            % Value
        RollStab              matlab.ui.control.NumericEditField % [-Inf Inf]
        Label51               matlab.ui.control.Label            % Min
        RollStabMin           matlab.ui.control.NumericEditField % [-Inf Inf]
        Label52               matlab.ui.control.Label            % Max
        RollStabMax           matlab.ui.control.NumericEditField % [-Inf Inf]
        Label53               matlab.ui.control.Label            % Stabilize
        Label54               matlab.ui.control.Label            % P
        Label55               matlab.ui.control.Label            % Value
        YawStab               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label56               matlab.ui.control.Label            % Min
        YawStabMin            matlab.ui.control.NumericEditField % [-Inf Inf]
        Label57               matlab.ui.control.Label            % Max
        YawStabMax            matlab.ui.control.NumericEditField % [-Inf Inf]
        Label58               matlab.ui.control.Label            % Measurement Time (seconds)
        MeasurementTime       matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField3       matlab.ui.control.Label            % Console
        Console               matlab.ui.control.EditField       
        Label59               matlab.ui.control.Label            % by Samuel Pownall
    end

    properties (Access = private)
        isConnected = false; % Is a connection to a MAVLink network active
        address = '127.0.0.1'; % MAVLINK server address
        port = 14552; % MAVLINK server port
        tcp % TCP handle
        
        pitchGains; %Pitch gain struct
        rollGains; %Roll gain struct
        yawGains; %Yaw gain struct
        lockPitchRoll = true; %Pitch and roll gains must be equal
    end

    methods (Access = private)
        
        function gain = gainLimit(app,input,min,max)
            if input > max
                gain = max;
            elseif input < min;
                gain = min;
            else
                gain = input;
            end
        end

        function min = minLimit(app,input,max)
            if input > max
                min = max;
            elseif input < 0.01;
                min = 0.01;
            else
                min = input;
            end
        end
        
        function max = maxLimit(app,input,min)
            if input < min
                max = min;
            elseif input > 1;
                max = 1;
            else
                max = input;
            end
        end
        
        function enableGainBoxes(app)
            app.PitchStab.Enable = 'on'; app.PitchStabMin.Enable = 'on'; app.PitchStabMax.Enable = 'on';
            app.PitchP.Enable = 'on'; app.PitchPMin.Enable = 'on'; app.PitchPMax.Enable = 'on';
            app.PitchI.Enable = 'on'; app.PitchIMin.Enable = 'on'; app.PitchIMax.Enable = 'on';
            app.PitchD.Enable = 'on'; app.PitchDMin.Enable = 'on'; app.PitchDMax.Enable = 'on';
            
            app.RollStab.Enable = 'on'; app.RollStabMin.Enable = 'on'; app.RollStabMax.Enable = 'on';
            app.RollP.Enable = 'on'; app.RollPMin.Enable = 'on'; app.RollPMax.Enable = 'on';
            app.RollI.Enable = 'on'; app.RollIMin.Enable = 'on'; app.RollIMax.Enable = 'on';
            app.RollD.Enable = 'on'; app.RollDMin.Enable = 'on'; app.RollDMax.Enable = 'on';
            
            app.YawStab.Enable = 'on'; app.YawStabMin.Enable = 'on'; app.YawStabMax.Enable = 'on';
            app.YawP.Enable = 'on'; app.YawPMin.Enable = 'on'; app.YawPMax.Enable = 'on';
            app.YawI.Enable = 'on'; app.YawIMin.Enable = 'on'; app.YawIMax.Enable = 'on';
            app.YawD.Enable = 'on'; app.YawDMin.Enable = 'on'; app.YawDMax.Enable = 'on';
        end
        
        function disableGainBoxes(app)
            app.PitchStab.Enable = 'off'; app.PitchStabMin.Enable = 'off'; app.PitchStabMax.Enable = 'off';
            app.PitchP.Enable = 'off'; app.PitchPMin.Enable = 'off'; app.PitchPMax.Enable = 'off';
            app.PitchI.Enable = 'off'; app.PitchIMin.Enable = 'off'; app.PitchIMax.Enable = 'off';
            app.PitchD.Enable = 'off'; app.PitchDMin.Enable = 'off'; app.PitchDMax.Enable = 'off'; 
            
            app.RollStab.Enable = 'off'; app.RollStabMin.Enable = 'off'; app.RollStabMax.Enable = 'off';
            app.RollP.Enable = 'off'; app.RollPMin.Enable = 'off'; app.RollPMax.Enable = 'off';
            app.RollI.Enable = 'off'; app.RollIMin.Enable = 'off'; app.RollIMax.Enable = 'off';
            app.RollD.Enable = 'off'; app.RollDMin.Enable = 'off'; app.RollDMax.Enable = 'off';
            
            app.YawStab.Enable = 'off'; app.YawStabMin.Enable = 'off'; app.YawStabMax.Enable = 'off';
            app.YawP.Enable = 'off'; app.YawPMin.Enable = 'off'; app.YawPMax.Enable = 'off';
            app.YawI.Enable = 'off'; app.YawIMin.Enable = 'off'; app.YawIMax.Enable = 'off';
            app.YawD.Enable = 'off'; app.YawDMin.Enable = 'off'; app.YawDMax.Enable = 'off';
        end
        
        function downloadGains(app)
            msg_param_request_list.send(app.tcp,1,1);
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pitchGains = struct('PValue',0,'IValue',0,'DValue',0,'StabValue',0,...
                'PMin',0,'IMin',0,'DMin',0,'StabMin',0,'PMax',0,'IMax',0,'DMax',0,'StabMax',0);
            
            app.rollGains = struct('PValue',0,'IValue',0,'DValue',0,'StabValue',0,...
                'PMin',0,'IMin',0,'DMin',0,'StabMin',0,'PMax',0,'IMax',0,'DMax',0,'StabMax',0);
            
            app.yawGains = struct('PValue',0,'IValue',0,'DValue',0,'StabValue',0,...
                'PMin',0,'IMin',0,'DMin',0,'StabMin',0,'PMax',0,'IMax',0,'DMax',0,'StabMax',0);
        end

        % AddressField value changed function
        function updateAddress(app)
            app.address = app.AddressField.Value;
        end

        % PortField value changed function
        function updatePort(app)
            app.port = str2double(app.PortField.Value);
        end

        % ConnectButton button pushed function
        function toggleConnection(app)
            if app.isConnected
                clear app.tcp;
                app.ConnectButton.Text = 'Connect';
                app.ConnectStatus.Value = 'DISCONNECTED';
                app.ConnectStatus.FontColor = [0.8 0.0 0.0];
                app.AddressField.Editable = 'on';
                app.PortField.Editable = 'on';
                app.RefreshGains.Enable = 'off';
                app.WriteGains.Enable = 'off';
                app.StartMeasurement.Enable = 'off';
                disableGainBoxes(app);
                app.isConnected = false;
            else
                try
                    app.tcp = tcpclient(app.address,app.port);
                    app.ConnectButton.Text = 'Disconnect';
                    app.ConnectStatus.Value = 'CONNECTED';
                    app.ConnectStatus.FontColor = [0.0 0.8 0.0];
                    app.AddressField.Editable = 'off';
                    app.PortField.Editable = 'off';
                    app.RefreshGains.Enable = 'on';
                    app.WriteGains.Enable = 'on';
                    app.StartMeasurement.Enable = 'on';
                    app.Console.Value = 'Connected successfully. Downloading gains from Arducopter.';
                    downloadGains(app);
                    enableGainBoxes(app);
                    app.isConnected = true;
                catch
                    app.Console.Value = 'Could not connect. Are the address and port correct?';
                end  
            end
        end

        % PitchP value changed function
        function PitchPValueChanged(app)
            app.PitchP.Value = gainLimit(app,app.PitchP.Value,app.PitchPMin.Value,app.PitchPMax.Value);
        end

        % PitchI value changed function
        function PitchIValueChanged(app)
            app.PitchI.Value = gainLimit(app,app.PitchI.Value,app.PitchIMin.Value,app.PitchIMax.Value);
        end

        % PitchD value changed function
        function PitchDValueChanged(app)
            app.PitchD.Value = gainLimit(app,app.PitchD.Value,app.PitchDMin.Value,app.PitchDMax.Value);
        end

        % PitchStab value changed function
        function PitchStabValueChanged(app)
            app.PitchStab.Value = gainLimit(app,app.PitchStab.Value,app.PitchStabMin.Value,app.PitchStabMax.Value);
        end

        % RollP value changed function
        function RollPValueChanged(app)
            app.RollP.Value = gainLimit(app,app.RollP.Value,app.RollPMin.Value,app.RollPMax.Value);
        end

        % RollI value changed function
        function RollIValueChanged(app)
            app.RollI.Value = gainLimit(app,app.RollI.Value,app.RollIMin.Value,app.RollIMax.Value);
        end

        % RollD value changed function
        function RollDValueChanged(app)
            app.RollD.Value = gainLimit(app,app.RollD.Value,app.RollDMin.Value,app.RollDMax.Value);
        end

        % RollStab value changed function
        function RollStabValueChanged(app)
            app.RollStab.Value = gainLimit(app,app.RollStab.Value,app.RollStabMin.Value,app.RollStabMax.Value);
        end

        % YawP value changed function
        function YawPValueChanged(app)
            app.YawP.Value = gainLimit(app,app.YawP.Value,app.YawPMin.Value,app.YawPMax.Value);
        end

        % YawI value changed function
        function YawIValueChanged(app)
            app.YawI.Value = gainLimit(app,app.YawI.Value,app.YawIMin.Value,app.YawIMax.Value);
        end

        % YawD value changed function
        function YawDValueChanged(app)
            app.YawD.Value = gainLimit(app,app.YawD.Value,app.YawDMin.Value,app.YawDMax.Value);
        end

        % YawStab value changed function
        function YawStabValueChanged(app)
            app.YawStab.Value = gainLimit(app,app.YawStab.Value,app.YawStabMin.Value,app.YawStabMax.Value);
        end

        % PitchPMin value changed function
        function PitchPMinValueChanged(app)
            app.PitchPMin.Value = minLimit(app,app.PitchPMin.Value,app.PitchPMax.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Address';
            app.AddressField = uieditfield(app.UIFigure, 'text');
            app.AddressField.ValueChangedFcn = createCallbackFcn(app, @updateAddress);
            app.AddressField.Value = '127.0.0.1';
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'Port';
            app.PortField = uieditfield(app.UIFigure, 'text');
            app.PortField.ValueChangedFcn = createCallbackFcn(app, @updatePort);
            app.PortField.Value = '14552';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @toggleConnection);
            app.ConnectButton.Text = 'Connect';
            app.ConnectStatus = uieditfield(app.UIFigure, 'text');
            app.ConnectStatus.Editable = 'off';
            app.ConnectStatus.Value = 'DISCONNECTED';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'MAVLink PID Tuning Example';
            app.PitchTuning = uipanel(app.UIFigure);
            app.PitchTuning.Title = 'Pitch Tuning';
            app.PitchTuning.Units = 'pixels';
            app.Label10 = uilabel(app.PitchTuning);
            app.Label10.Text = 'P';
            app.Label11 = uilabel(app.PitchTuning);
            app.Label11.Text = 'I';
            app.Label12 = uilabel(app.PitchTuning);
            app.Label12.Text = 'D';
            app.LabelNumericEditField = uilabel(app.PitchTuning);
            app.LabelNumericEditField.Text = 'Value';
            app.PitchP = uieditfield(app.PitchTuning, 'numeric');
            app.PitchP.ValueChangedFcn = createCallbackFcn(app, @PitchPValueChanged);
            app.PitchP.Enable = 'off';
            app.Label2 = uilabel(app.PitchTuning);
            app.Label2.Text = 'Value';
            app.PitchI = uieditfield(app.PitchTuning, 'numeric');
            app.PitchI.ValueChangedFcn = createCallbackFcn(app, @PitchIValueChanged);
            app.PitchI.Enable = 'off';
            app.Label3 = uilabel(app.PitchTuning);
            app.Label3.Text = 'Value';
            app.PitchD = uieditfield(app.PitchTuning, 'numeric');
            app.PitchD.ValueChangedFcn = createCallbackFcn(app, @PitchDValueChanged);
            app.PitchD.Enable = 'off';
            app.Label4 = uilabel(app.PitchTuning);
            app.Label4.Text = 'Min';
            app.PitchPMin = uieditfield(app.PitchTuning, 'numeric');
            app.PitchPMin.ValueChangedFcn = createCallbackFcn(app, @PitchPMinValueChanged);
            app.PitchPMin.Enable = 'off';
            app.Label5 = uilabel(app.PitchTuning);
            app.Label5.Text = 'Min';
            app.PitchIMin = uieditfield(app.PitchTuning, 'numeric');
            app.PitchIMin.Enable = 'off';
            app.Label6 = uilabel(app.PitchTuning);
            app.Label6.Text = 'Min';
            app.PitchDMin = uieditfield(app.PitchTuning, 'numeric');
            app.PitchDMin.Enable = 'off';
            app.Label7 = uilabel(app.PitchTuning);
            app.Label7.Text = 'Max';
            app.PitchPMax = uieditfield(app.PitchTuning, 'numeric');
            app.PitchPMax.Enable = 'off';
            app.Label8 = uilabel(app.PitchTuning);
            app.Label8.Text = 'Max';
            app.PitchIMax = uieditfield(app.PitchTuning, 'numeric');
            app.PitchIMax.Enable = 'off';
            app.Label9 = uilabel(app.PitchTuning);
            app.Label9.Text = 'Max';
            app.PitchDMax = uieditfield(app.PitchTuning, 'numeric');
            app.PitchDMax.Enable = 'off';
            app.Label40 = uilabel(app.PitchTuning);
            app.Label40.Text = 'Stabilize';
            app.Label41 = uilabel(app.PitchTuning);
            app.Label41.Text = 'Rate';
            app.Label42 = uilabel(app.PitchTuning);
            app.Label42.Text = 'P';
            app.Label43 = uilabel(app.PitchTuning);
            app.Label43.Text = 'Value';
            app.PitchStab = uieditfield(app.PitchTuning, 'numeric');
            app.PitchStab.ValueChangedFcn = createCallbackFcn(app, @PitchStabValueChanged);
            app.PitchStab.Enable = 'off';
            app.Label44 = uilabel(app.PitchTuning);
            app.Label44.Text = 'Min';
            app.PitchStabMin = uieditfield(app.PitchTuning, 'numeric');
            app.PitchStabMin.Enable = 'off';
            app.Label45 = uilabel(app.PitchTuning);
            app.Label45.Text = 'Max';
            app.PitchStabMax = uieditfield(app.PitchTuning, 'numeric');
            app.PitchStabMax.Enable = 'off';
            app.RollTuning = uipanel(app.UIFigure);
            app.RollTuning.Title = 'Roll Tuning';
            app.RollTuning.Units = 'pixels';
            app.Label14 = uilabel(app.RollTuning);
            app.Label14.Text = 'P';
            app.Label15 = uilabel(app.RollTuning);
            app.Label15.Text = 'I';
            app.Label16 = uilabel(app.RollTuning);
            app.Label16.Text = 'D';
            app.Label18 = uilabel(app.RollTuning);
            app.Label18.Text = 'Value';
            app.RollP = uieditfield(app.RollTuning, 'numeric');
            app.RollP.ValueChangedFcn = createCallbackFcn(app, @RollPValueChanged);
            app.RollP.Enable = 'off';
            app.Label19 = uilabel(app.RollTuning);
            app.Label19.Text = 'Value';
            app.RollI = uieditfield(app.RollTuning, 'numeric');
            app.RollI.ValueChangedFcn = createCallbackFcn(app, @RollIValueChanged);
            app.RollI.Enable = 'off';
            app.Label20 = uilabel(app.RollTuning);
            app.Label20.Text = 'Value';
            app.RollD = uieditfield(app.RollTuning, 'numeric');
            app.RollD.ValueChangedFcn = createCallbackFcn(app, @RollDValueChanged);
            app.RollD.Enable = 'off';
            app.Label21 = uilabel(app.RollTuning);
            app.Label21.Text = 'Min';
            app.RollPMin = uieditfield(app.RollTuning, 'numeric');
            app.RollPMin.Enable = 'off';
            app.Label22 = uilabel(app.RollTuning);
            app.Label22.Text = 'Min';
            app.RollIMin = uieditfield(app.RollTuning, 'numeric');
            app.RollIMin.Enable = 'off';
            app.Label23 = uilabel(app.RollTuning);
            app.Label23.Text = 'Min';
            app.RollDMin = uieditfield(app.RollTuning, 'numeric');
            app.RollDMin.Enable = 'off';
            app.Label24 = uilabel(app.RollTuning);
            app.Label24.Text = 'Max';
            app.RollPMax = uieditfield(app.RollTuning, 'numeric');
            app.RollPMax.Enable = 'off';
            app.Label25 = uilabel(app.RollTuning);
            app.Label25.Text = 'Max';
            app.RollIMax = uieditfield(app.RollTuning, 'numeric');
            app.RollIMax.Enable = 'off';
            app.Label26 = uilabel(app.RollTuning);
            app.Label26.Text = 'Max';
            app.RollDMax = uieditfield(app.RollTuning, 'numeric');
            app.RollDMax.Enable = 'off';
            app.Label46 = uilabel(app.RollTuning);
            app.Label46.Text = 'Rate';
            app.Label48 = uilabel(app.RollTuning);
            app.Label48.Text = 'Stabilize';
            app.Label49 = uilabel(app.RollTuning);
            app.Label49.Text = 'P';
            app.Label50 = uilabel(app.RollTuning);
            app.Label50.Text = 'Value';
            app.RollStab = uieditfield(app.RollTuning, 'numeric');
            app.RollStab.ValueChangedFcn = createCallbackFcn(app, @RollStabValueChanged);
            app.RollStab.Enable = 'off';
            app.Label51 = uilabel(app.RollTuning);
            app.Label51.Text = 'Min';
            app.RollStabMin = uieditfield(app.RollTuning, 'numeric');
            app.RollStabMin.Enable = 'off';
            app.Label52 = uilabel(app.RollTuning);
            app.Label52.Text = 'Max';
            app.RollStabMax = uieditfield(app.RollTuning, 'numeric');
            app.RollStabMax.Enable = 'off';
            app.YawTuning = uipanel(app.UIFigure);
            app.YawTuning.Title = 'Yaw Tuning';
            app.YawTuning.Units = 'pixels';
            app.Label27 = uilabel(app.YawTuning);
            app.Label27.Text = 'P';
            app.Label28 = uilabel(app.YawTuning);
            app.Label28.Text = 'I';
            app.Label29 = uilabel(app.YawTuning);
            app.Label29.Text = 'D';
            app.Label31 = uilabel(app.YawTuning);
            app.Label31.Text = 'Value';
            app.YawP = uieditfield(app.YawTuning, 'numeric');
            app.YawP.ValueChangedFcn = createCallbackFcn(app, @YawPValueChanged);
            app.YawP.Enable = 'off';
            app.Label32 = uilabel(app.YawTuning);
            app.Label32.Text = 'Value';
            app.YawI = uieditfield(app.YawTuning, 'numeric');
            app.YawI.ValueChangedFcn = createCallbackFcn(app, @YawIValueChanged);
            app.YawI.Enable = 'off';
            app.Label33 = uilabel(app.YawTuning);
            app.Label33.Text = 'Value';
            app.YawD = uieditfield(app.YawTuning, 'numeric');
            app.YawD.ValueChangedFcn = createCallbackFcn(app, @YawDValueChanged);
            app.YawD.Enable = 'off';
            app.Label34 = uilabel(app.YawTuning);
            app.Label34.Text = 'Min';
            app.YawPMin = uieditfield(app.YawTuning, 'numeric');
            app.YawPMin.Enable = 'off';
            app.Label35 = uilabel(app.YawTuning);
            app.Label35.Text = 'Min';
            app.YawIMin = uieditfield(app.YawTuning, 'numeric');
            app.YawIMin.Enable = 'off';
            app.Label36 = uilabel(app.YawTuning);
            app.Label36.Text = 'Min';
            app.YawDMin = uieditfield(app.YawTuning, 'numeric');
            app.YawDMin.Enable = 'off';
            app.Label37 = uilabel(app.YawTuning);
            app.Label37.Text = 'Max';
            app.YawPMax = uieditfield(app.YawTuning, 'numeric');
            app.YawPMax.Enable = 'off';
            app.Label38 = uilabel(app.YawTuning);
            app.Label38.Text = 'Max';
            app.YawIMax = uieditfield(app.YawTuning, 'numeric');
            app.YawIMax.Enable = 'off';
            app.Label39 = uilabel(app.YawTuning);
            app.Label39.Text = 'Max';
            app.YawDMax = uieditfield(app.YawTuning, 'numeric');
            app.YawDMax.Enable = 'off';
            app.Label47 = uilabel(app.YawTuning);
            app.Label47.Text = 'Rate';
            app.Label53 = uilabel(app.YawTuning);
            app.Label53.Text = 'Stabilize';
            app.Label54 = uilabel(app.YawTuning);
            app.Label54.Text = 'P';
            app.Label55 = uilabel(app.YawTuning);
            app.Label55.Text = 'Value';
            app.YawStab = uieditfield(app.YawTuning, 'numeric');
            app.YawStab.ValueChangedFcn = createCallbackFcn(app, @YawStabValueChanged);
            app.YawStab.Enable = 'off';
            app.Label56 = uilabel(app.YawTuning);
            app.Label56.Text = 'Min';
            app.YawStabMin = uieditfield(app.YawTuning, 'numeric');
            app.YawStabMin.Enable = 'off';
            app.Label57 = uilabel(app.YawTuning);
            app.Label57.Text = 'Max';
            app.YawStabMax = uieditfield(app.YawTuning, 'numeric');
            app.YawStabMax.Enable = 'off';
            app.PitchPlotBorder = uipanel(app.UIFigure);
            app.PitchPlotBorder.Units = 'pixels';
            app.PitchPlot = uiaxes(app.PitchPlotBorder);
            title(app.PitchPlot, 'Pitch Angle');
            xlabel(app.PitchPlot, 'Time (s)');
            ylabel(app.PitchPlot, 'Angle (deg)');
            app.RollPlotBorder = uipanel(app.UIFigure);
            app.RollPlotBorder.Units = 'pixels';
            app.RollPlot = uiaxes(app.RollPlotBorder);
            title(app.RollPlot, 'Roll Angle');
            xlabel(app.RollPlot, 'Time (s)');
            ylabel(app.RollPlot, 'Angle (deg)');
            app.YawPlotBorder = uipanel(app.UIFigure);
            app.YawPlotBorder.Units = 'pixels';
            app.YawPlot = uiaxes(app.YawPlotBorder);
            title(app.YawPlot, 'Yaw Angle');
            xlabel(app.YawPlot, 'Time(s)');
            ylabel(app.YawPlot, 'Angle (deg)');
            app.WriteGains = uibutton(app.UIFigure, 'push');
            app.WriteGains.Enable = 'off';
            app.WriteGains.Text = 'Write Gains';
            app.RefreshGains = uibutton(app.UIFigure, 'push');
            app.RefreshGains.Enable = 'off';
            app.RefreshGains.Text = 'Refresh Gains';
            app.LockPitchRoll = uicheckbox(app.UIFigure);
            app.LockPitchRoll.Text = 'Lock Pitch/Roll Gains';
            app.LockPitchRoll.Value = true;
            app.StartMeasurement = uibutton(app.UIFigure, 'push');
            app.StartMeasurement.Enable = 'off';
            app.StartMeasurement.Text = 'Start Measurement';
            app.Label58 = uilabel(app.UIFigure);
            app.Label58.Text = 'Measurement Time (seconds)';
            app.MeasurementTime = uieditfield(app.UIFigure, 'numeric');
            app.MeasurementTime.Value = 10;
            app.LabelEditField3 = uilabel(app.UIFigure);
            app.LabelEditField3.Text = 'Console';
            app.Console = uieditfield(app.UIFigure, 'text');
            app.Console.Editable = 'off';
            app.Label59 = uilabel(app.UIFigure);
            app.Label59.Text = 'by Samuel Pownall';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GainTest()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end